classdef InteractiveImageRegistration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Finished            matlab.ui.control.Button
        UpdateRegistration  matlab.ui.control.Button
        Instructions        matlab.ui.control.TextArea
        InstructionsLabel   matlab.ui.control.Label
        TranslationY        matlab.ui.control.Spinner
        TranslationYLabel   matlab.ui.control.Label
        TranslationX        matlab.ui.control.Spinner
        TranslationXLabel   matlab.ui.control.Label
        ScalingY            matlab.ui.control.Spinner
        ScalingYLabel       matlab.ui.control.Label
        ScalingX            matlab.ui.control.Spinner
        ScalingXLabel       matlab.ui.control.Label
        Rotation            matlab.ui.control.Spinner
        RotationLabel       matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Moving % image to be registered
        Warped % warped image
        RegionsTable  % Allen atlas regions table
        Outline     % brain outline 
        Params % [rot,scaleX,scaleY,transX,transY]
        Tform  % transformation object
        InitialTform %  initial transformation matrix (from control point reg)
        OrigDims % dimensions of original image
        TargetDims % dimensions of target standard space
    end
    
    methods (Access = private)
        
        function plot_allen_outlines_app(app)
            
            % plot warped image
            imagesc(app.UIAxes,app.Warped)
            colormap(app.UIAxes,NeuroWide.cmp.inferno)
            caxis(app.UIAxes,[0,1])
            hold(app.UIAxes,'on')
            % plot parcel outlines 
            plot(app.Outline(:,1),app.Outline(:,2),'red','LineWidth',2,'Parent',app.UIAxes)
            for r = 1:height(app.RegionsTable)
                plot(app.UIAxes,app.RegionsTable.left_x{r},app.RegionsTable.left_y{r},'red','LineStyle','-','LineWidth',1.5)
                plot(app.UIAxes,app.RegionsTable.right_x{r},app.RegionsTable.right_y{r},'red','LineStyle','-','LineWidth',1.5)
            end
        end
     
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, moving, fixed, regions_table, outline, init_tform, percent)
            % preprocess the image for better contrast
            
            max_intensity = max(moving(:));
            upper_lim = max_intensity*percent;
            moving_thresh = moving;
            moving_thresh(moving > upper_lim) = upper_lim;
                        
            app.Moving = mat2gray(moving_thresh);
            app.OrigDims = size(moving);
            app.TargetDims = size(fixed);
            app.RegionsTable = regions_table;
            app.Outline = outline;
            app.Params = [0 1 1 0 0];
            app.InitialTform = init_tform;
            
            % create overlay of edges
            app.Warped = imwarp(app.Moving,init_tform,'OutputView',imref2d(app.TargetDims));
            
            % plot         
            plot_allen_outlines_app(app)    
        
            set(app.UIAxes,'DataAspectRatio',[2 2 1])

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {533, 533};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: UpdateRegistration
        function UpdateRegistrationButtonPushed(app, event)
            % recalculate the registration;
            
            % Rotation
            angle = app.Rotation.Value;
            q = angle/360 * 2*pi;
            R = [cos(q) sin(q) 0;
                -sin(q) cos(q) 0;
                0 0 1];
            
            % Scaling
            sx = 1 + app.ScalingX.Value;
            sy = 1 + app.ScalingY.Value;
            Sc = [sx 0 0;
                0 sy 0;
                0 0 1]; 
            
            % Translation 
            % first calculate offset from any change in scaling
            delta_x = app.TargetDims(1) * (app.Params(2) - sx);
            delta_y = app.TargetDims(2) * (app.Params(3) - sy);

            tx = app.TranslationX.Value + .5*delta_x;
            ty = app.TranslationY.Value + .5*delta_y;
            app.TranslationX.Value = tx;
            app.TranslationY.Value = ty;
            T = [1 0 0;
                0 1 0;
                tx ty 1];
            
            % create and save transformation
            combined_tform = affine2d(app.InitialTform.T*R*Sc*T);
            app.Tform = combined_tform;
            
            % apply transformation
            app.Warped = imwarp(app.Moving,combined_tform,'OutputView',imref2d(app.TargetDims));
            
            % plot      
            plot_allen_outlines_app(app)   
         
            % update parameters
            app.Params = [angle,sx,sy,tx,ty];
        end

        % Callback function: Finished, UIAxes
        function CloseApp(app, event)
            assignin('base','FinalTform',app.Tform) 
            close(app.UIFigure) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RotationLabel = uilabel(app.LeftPanel);
            app.RotationLabel.Text = 'Rotation';
            app.Rotation = uispinner(app.LeftPanel);
            app.ScalingXLabel = uilabel(app.LeftPanel);
            app.ScalingXLabel.Text = 'Scaling X';
            app.ScalingX = uispinner(app.LeftPanel);
            app.ScalingX.Step = 0.01;
            app.ScalingX.Limits = [-0.999999 Inf];
            app.ScalingYLabel = uilabel(app.LeftPanel);
            app.ScalingYLabel.Text = 'Scaling Y';
            app.ScalingY = uispinner(app.LeftPanel);
            app.ScalingY.Step = 0.01;
            app.TranslationXLabel = uilabel(app.LeftPanel);
            app.TranslationXLabel.Text = 'Translation X';
            app.TranslationX = uispinner(app.LeftPanel);
            app.TranslationYLabel = uilabel(app.LeftPanel);
            app.TranslationYLabel.Text = 'Translation Y';
            app.TranslationY = uispinner(app.LeftPanel);
            app.InstructionsLabel = uilabel(app.LeftPanel);
            app.InstructionsLabel.Text = 'Instructions';
            app.Instructions = uitextarea(app.LeftPanel);
            app.Instructions.Value = {'Update the values in the boxes below and then click "Update Registration".'};
            app.UpdateRegistration = uibutton(app.LeftPanel, 'push');
            app.UpdateRegistration.ButtonPushedFcn = createCallbackFcn(app, @UpdateRegistrationButtonPushed, true);
            app.UpdateRegistration.Text = 'Update Registration';
            app.Finished = uibutton(app.LeftPanel, 'push');
            app.Finished.ButtonPushedFcn = createCallbackFcn(app, @CloseApp, true);
            app.Finished.Text = 'Finished';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Image Alignment')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @CloseApp, true);
            colormap(app.UIAxes, 'parula')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InteractiveImageRegistration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end